classdef Covid19_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Global
        Dates
        Country
        States
        HoleCases
        HoleDeaths
        DailyOnly
        Slider
    end
    
    methods (Access = private)
        function PlotDeaths(app)
            cla(app.UIAxes,'reset');
            if app.CumulativeButton.Value
                if app.AveragedofdaysSlider.Value == 1
                    if app.Country == 0
                        plot(app.UIAxes, app.HoleDeaths(:,1))
                        
                        title(app.UIAxes,'Cumulative number of deaths globally')
                    else
                        if app.States == 0
                            plot(app.UIAxes, app.Global.places(app.Country).data(:,2))
                            
                            title(app.UIAxes,["Cumulative number of deaths in ",string(app.Global.places(app.Country).name)])
                        else
                            plot(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,1))
                            
                            title(app.UIAxes, ["Cumulative number of deaths in ", string(app.Global.places(app.Country).places(app.States).name)])
                        end
                    end
                else                        %Slider
                    if app.Country == 0
                        plot(app.UIAxes, movmean(app.HoleDeaths(:,1),[app.Slider-1 0]))
                        
                        title(app.UIAxes,["Cumulative number of deaths globally (",app.Slider," days mean)"])
                    else
                        if app.States == 0
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).data(:,2),[app.Slider-1 0]))
                            
                            title(app.UIAxes,["Cumulative number of deaths in ",string(app.Global.places(app.Country).name)," (",string(app.Slider)," days mean)"])
                        else
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,2),[app.Slider-1 0]))
                            
                            title(app.UIAxes,["Cumulative number of deaths in ", string(app.Global.places(app.Country).places(app.States).name), "(",string(app.Slider)," days mean)"])
                        end
                    end
                end
            else % Daily
                if app.AveragedofdaysSlider.Value == 1
                    if app.Country == 0
                        plot(app.UIAxes, app.HoleDeaths(:,2))
                        title(app.UIAxes,'Daily number of deaths globally')
                    else
                        if app.States == 0
                            plot(app.UIAxes, app.Global.places(app.Country).data(:,4))
                            title(app.UIAxes,["Daily number of deaths in ",string(app.Global.places(app.Country).name)])
                        else
                            plot(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,4))
                            title(app.UIAxes, ["Daily number of deaths in ", string(app.Global.places(app.Country).places(app.States).name)])
                        end
                    end
                else                        %Slider
                    if app.Country == 0
                        plot(app.UIAxes, movmean(app.HoleDeaths(:,2),[app.Slider-1 0]))%Düzeltilecek
                        title(app.UIAxes,["Daily number of deaths globally (",app.Slider," days mean)"])
                    else
                        if app.States == 0
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).data(:,4),[app.Slider-1 0]))
                            title(app.UIAxes,["Daily number of deaths in ",string(app.Global.places(app.Country).name)," (",string(app.Slider)," days mean)"])
                        else
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,4),[app.Slider-1 0]))
                            title(app.UIAxes,["Daily number of deaths in ", string(app.Global.places(app.Country).places(app.States).name), "(",string(app.Slider)," days mean)"])
                        end
                    end
                end
                
            end
            datetick(app.UIAxes,'x',6,'keeplimits')
            grid(app.UIAxes,"on")
            
        end
        function PlotBoth(app)
            cla(app.UIAxes,'reset');
            if app.CumulativeButton.Value
                if app.AveragedofdaysSlider.Value == 1
                    if app.Country == 0
                        yyaxis(app.UIAxes,"left")
                        bar(app.UIAxes, app.HoleCases(:,1))
                        yyaxis(app.UIAxes,"right")
                        plot(app.UIAxes,app.HoleDeaths(:,1))
                        title(app.UIAxes,'Cumulative number of cases and deaths globally')
                    else
                        if app.States == 0
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, app.Global.places(app.Country).data(:,1))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes,app.Global.places(app.Country).data(:,2))
                            title(app.UIAxes,["Cumulative number of cases and deaths in ",string(app.Global.places(app.Country).name)])
                        else
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,1))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,2))
                            title(app.UIAxes, ["Cumulative number of cases and deaths in ", string(app.Global.places(app.Country).places(app.States).name)])
                        end
                    end
                else                        %Slider
                    if app.Country == 0
                        yyaxis(app.UIAxes,"left")
                        bar(app.UIAxes, movmean(app.HoleCases(:,1),[app.Slider-1 0]))
                        yyaxis(app.UIAxes,"right")
                        plot(app.UIAxes, movmean(app.HoleDeaths(:,1),[app.Slider-1 0]))
                        title(app.UIAxes,["Cumulative number of cases globally (",app.Slider," days mean)"])
                    else
                        if app.States == 0
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).data(:,1),[app.Slider-1 0]))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).data(:,2),[app.Slider-1 0]))
                            title(app.UIAxes,["Cumulative number of cases and deaths in ",string(app.Global.places(app.Country).name)," (",string(app.Slider)," days mean)"])
                        else
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,1),[app.Slider-1 0]))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,2),[app.Slider-1 0]))
                            title(app.UIAxes,["Cumulative number of cases and deaths in ", string(app.Global.places(app.Country).places(app.States).name), "(",string(app.Slider)," days mean)"])
                        end
                    end
                end
            else % Daily
                if app.AveragedofdaysSlider.Value == 1
                    if app.Country == 0
                        yyaxis(app.UIAxes,"left")
                        bar(app.UIAxes, app.HoleCases(:,2))
                        yyaxis(app.UIAxes,"right")
                        plot(app.UIAxes, app.Deaths(:,2))
                        title(app.UIAxes,'Daily number of cases and deaths globally')
                    else
                        if app.States == 0
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, app.Global.places(app.Country).data(:,3))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes, app.Global.places(app.Country).data(:,4))
                            title(app.UIAxes,["Daily number of cases and deaths in ",string(app.Global.places(app.Country).name)])
                        else
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,3))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,4))
                            title(app.UIAxes, ["Daily number of cases and deaths in ", string(app.Global.places(app.Country).places(app.States).name)])
                        end
                    end
                else                        %Slider
                    if app.Country == 0
                        yyaxis(app.UIAxes,"left")
                        bar(app.UIAxes, movmean(app.HoleCases(:,2),[app.Slider-1 0]))
                        yyaxis(app.UIAxes,"right")
                        plot(app.UIAxes, movmean(app.Deaths(:,2),[app.Slider-1 0]))
                        title(app.UIAxes,["Daily number of cases and deaths globally (",app.Slider," days mean)"])
                    else
                        if app.States == 0
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).data(:,3),[app.Slider-1 0]))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).data(:,4),[app.Slider-1 0]))
                            title(app.UIAxes,["Daily number of cases and deaths in ",string(app.Global.places(app.Country).name)," (",string(app.Slider)," days mean)"])
                        else
                            yyaxis(app.UIAxes,"left")
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,3),[app.Slider-1 0]))
                            yyaxis(app.UIAxes,"right")
                            plot(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,4),[app.Slider-1 0]))
                            title(app.UIAxes,["Daily number of cases and deaths in ", string(app.Global.places(app.Country).places(app.States).name), "(",string(app.Slider)," days mean)"])
                        end
                    end
                end
                
            end
            datetick(app.UIAxes,'x',6,'keeplimits')
            grid(app.UIAxes,"on")
        end
        
        
        function PlotCases(app)
            cla(app.UIAxes,'reset');
            if app.CumulativeButton.Value
                if app.AveragedofdaysSlider.Value == 1
                    if app.Country == 0
                        bar(app.UIAxes, app.HoleCases(:,1))
                        title(app.UIAxes,'Cumulative number of cases globally')
                    else
                        if app.States == 0
                            bar(app.UIAxes, app.Global.places(app.Country).data(:,1))
                            title(app.UIAxes,["Cumulative number of cases of ",string(app.Global.places(app.Country).name)])
                        else
                            bar(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,1))
                            title(app.UIAxes, ["Cumulative number of cases of ", string(app.Global.places(app.Country).places(app.States).name)])
                        end
                    end
                else                        %Slider
                    if app.Country == 0
                        bar(app.UIAxes, movmean(app.HoleCases(:,1),[app.Slider-1 0]))%düzeltilecek
                        title(app.UIAxes,["Cumulative number of cases globally (",app.Slider," days mean)"])
                    else
                        if app.States == 0
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).data(:,1),[app.Slider-1 0]))
                            title(app.UIAxes,["Cumulative number of cases of ",string(app.Global.places(app.Country).name)," (",string(app.Slider)," days mean)"])
                        else
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,1),[app.Slider-1 0]))
                            title(app.UIAxes,["Cumulative number of cases of ", string(app.Global.places(app.Country).places(app.States).name), "(",string(app.Slider)," days mean)"])
                        end
                    end
                end
            else % Daily
                if app.AveragedofdaysSlider.Value == 1
                    if app.Country == 0
                        bar(app.UIAxes, app.HoleCases(:,2))%Düzeltilecek
                        title(app.UIAxes,'Daily number of cases globally')
                    else
                        if app.States == 0
                            bar(app.UIAxes, app.Global.places(app.Country).data(:,3))
                            title(app.UIAxes,["Daily number of cases of ",string(app.Global.places(app.Country).name)])
                        else
                            bar(app.UIAxes, app.Global.places(app.Country).places(app.States).data(:,3))
                            title(app.UIAxes, ["Daily number of cases of ", string(app.Global.places(app.Country).places(app.States).name)])
                        end
                    end
                else                        %Slider
                    if app.Country == 0
                        bar(app.UIAxes, movmean(app.HoleCases(:,2),[app.Slider-1 0]))%Düzeltilecek
                        title(app.UIAxes,["Daily number of cases globally (",app.Slider," days mean)"])
                    else
                        if app.States == 0
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).data(:,3),[app.Slider-1 0]))
                            title(app.UIAxes,["Daily number of cases of ",string(app.Global.places(app.Country).name)," (",string(app.Slider)," days mean)"])
                        else
                            bar(app.UIAxes, movmean(app.Global.places(app.Country).places(app.States).data(:,3),[app.Slider-1 0]))
                            title(app.UIAxes,["Daily number of cases of ", string(app.Global.places(app.Country).places(app.States).name), "(",string(app.Slider)," days mean)"])
                        end
                    end
                end
                
            end
            datetick(app.UIAxes,'x',6,'keeplimits')
            grid(app.UIAxes,"on")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            load covid_data.mat covid_data
            countries = string(unique(covid_data(2:end,1)));
            M = splitapply(@(x) {x}, covid_data(2:end,2:end).', findgroups(covid_data(2:end,1).'));
            app.Global = Object_constructor("Global");
            p=[M{1,:}];
            col=find(isnan(findgroups(p(1,:))));
            row=ones(1,size(col,2));
            sz=[1,size(p,2)];
            ind = sub2ind(sz,row,col);
            app.Global.data=cell2mat(p(2:end,ind));
            %app.Dates = string(covid_data(1,3:end));
            app.Global.places = Object_constructor(countries);
            for ii=1:numel(countries)
                country_data = M{ii};
                app.Global.places(ii).data = cell2mat(country_data(2:end,1));
                app.Global.places(ii).data(:,3)=zeros(numel(country_data(2:end,1)),1);
                app.Global.places(ii).data(:,4)=zeros(numel(country_data(2:end,1)),1);
                app.Global.places(ii).data(1,3)= app.Global.places(ii).data(1,1);
                app.Global.places(ii).data(1,4)= app.Global.places(ii).data(1,2);
                for pp=1:size(country_data(2:end,1),1)-1
                    if app.Global.places(ii).data(pp+1,1)-app.Global.places(ii).data(pp,1) > 0
                        app.Global.places(ii).data(pp+1,3)= app.Global.places(ii).data(pp+1,1)-app.Global.places(ii).data(pp,1);
                    end
                    if app.Global.places(ii).data(pp+1,2)-app.Global.places(ii).data(pp,2) > 0
                        app.Global.places(ii).data(pp+1,4)= app.Global.places(ii).data(pp+1,2)-app.Global.places(ii).data(pp,2);
                    end
                end
                app.Global.places(ii).places = Object_constructor(string(country_data(1,2:end)));
                for jj=1:size(country_data(1,2:end),2)
                    state_data = country_data(2:end, jj+1);
                    app.Global.places(ii).places(jj).data = cell2mat(state_data);% BURADA KALDIN
                    if ~isempty(app.Global.places(ii).places(1).name)
                        app.Global.places(ii).places(jj).data(:,3) = zeros(numel(country_data(2:end,1)),1);
                        app.Global.places(ii).places(jj).data(:,4) = zeros(numel(country_data(2:end,1)),1);
                        app.Global.places(ii).places(jj).data(1,3) = app.Global.places(ii).places(jj).data(1,1);
                        app.Global.places(ii).places(jj).data(1,4) = app.Global.places(ii).places(jj).data(1,2);
                        for pp=1:size(country_data(2:end,1),1)-1
                            if app.Global.places(ii).places(jj).data(pp+1,1) - app.Global.places(ii).places(jj).data(pp,1) > 0
                                app.Global.places(ii).places(jj).data(pp+1,3)= app.Global.places(ii).places(jj).data(pp+1,1) - app.Global.places(ii).places(jj).data(pp,1);
                            else
                                app.Global.places(ii).places(jj).data(pp+1,3)=0;
                            end
                            if app.Global.places(ii).places(jj).data(pp+1,2) - app.Global.places(ii).places(jj).data(pp,2) > 0
                                app.Global.places(ii).places(jj).data(pp+1,4)= app.Global.places(ii).places(jj).data(pp+1,2) - app.Global.places(ii).places(jj).data(pp,2);
                            else
                                app.Global.places(ii).places(jj).data(pp+1,4)=0;
                            end
                        end
                    end
                end
            end
            app.HoleCases = app.Global.data.';
            app.HoleDeaths = app.HoleCases;
            app.HoleCases = sum(app.HoleCases(1:2:end-1,:)).';
            app.HoleDeaths = sum(app.HoleDeaths(2:2:end,:)).';
            app.HoleCases(:,2) = zeros(numel(app.HoleCases),1);
            app.HoleDeaths(:,2) = zeros(numel(app.HoleDeaths),1);
            app.HoleCases(1,2) = app.HoleCases(1,1);
            app.HoleDeaths(1,2) = app.HoleDeaths(1,1);
            for tt=1:size(app.HoleCases,1)-1
                if app.HoleCases(tt+1,1) - app.HoleCases(tt,1) > 0
                    app.HoleCases(tt+1,2) = app.HoleCases(tt+1,1) - app.HoleCases(tt,1);
                end
                if app.HoleDeaths(tt+1,1) - app.HoleDeaths(tt,1) > 0
                    app.HoleDeaths(tt+1,2) = app.HoleDeaths(tt+1,1) - app.HoleDeaths(tt,1);
                end
            end
            app.CountryListBox.Items = ["Global",app.Global.places.name];
            app.StateorRegionListBox.Items = "All";
            app.AveragedofdaysSlider.Value = 1;
            app.Country = 0;
            app.CasesButton.Value = 1;
            app.PlotCases();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            if isequal(app.CountryListBox.Value,"Global")
                app.Country = 0;
                app.StateorRegionListBox.Items = "All";
            else
                app.Country = find(string(app.CountryListBox.Value) == [app.Global.places.name]);
                if ~isempty(app.Global.places(app.Country).places(1).name)
                    app.StateorRegionListBox.Items = ["All",app.Global.places(app.Country).places.name];
                else
                    app.StateorRegionListBox.Items= "All";
                end
            end
            app.States = 0;
            app.CasesButton.Value = 1;
            app.CumulativeButton.Value = 1;
            app.AveragedofdaysSlider.Value = 1;
            app.PlotCases();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            if isequal(app.StateorRegionListBox.Value,"All")
                app.States = 0;
                app.CasesButton.Value = 1;
                app.CumulativeButton.Value = 1;
                app.PlotCases();
                return;
            else
                app.States = find(app.StateorRegionListBox.Value == [app.Global.places(app.Country).places.name]);
                app.CasesButton.Value = 1;
                app.CumulativeButton.Value = 1;
                app.PlotCases();
            end
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            if app.CasesButton.Value
                app.PlotCases();
            elseif app.DeathsButton.Value
                app.PlotDeaths();
            else
                app.PlotBoth();
            end
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            if app.CasesButton.Value
                app.PlotCases();
            elseif app.DeathsButton.Value
                app.PlotDeaths();
            else
                app.PlotBoth();
            end
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.Slider = round(app.AveragedofdaysSlider.Value);
            if app.CasesButton.Value
                app.PlotCases();
            elseif app.DeathsButton.Value
                app.PlotDeaths();
            else
                app.PlotBoth();
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged  # of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid19_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end